classdef CC_INJ_TracesViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ExitButton         matlab.ui.control.Button
        TraceSpinner       matlab.ui.control.Spinner
        TraceSpinnerLabel  matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data                % input from base workspace 
        APonsets            % input from base workspace
        APthresholds        % input from base workspace
        Ntraces             % input from base workspace
        NumberAPperTrace    % input from base workspace
        ymin
        ymax
    end
    
    methods (Access = private)
        
        function plotdata(app)
            time = app.data(:, 1) ./ 1000; % [s]
            cla(app.UIAxes, 'reset')
            hold(app.UIAxes, "on")
            plot(app.UIAxes, time, app.data(:, app.TraceSpinner.Value*2))
            if app.NumberAPperTrace(app.TraceSpinner.Value) > 0
                scatter(app.UIAxes, app.APonsets{1, app.TraceSpinner.Value}, app.APthresholds{1, app.TraceSpinner.Value}, 36, 'red', 'filled')
            end
            ylim(app.UIAxes, [app.ymin, app.ymax])
            xlim(app.UIAxes, [min(time), max(time)])
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Vm [mV]')
            hold(app.UIAxes, "off")
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.data = evalin('base','data');
            app.APonsets = evalin('base','APonsets');
            app.APthresholds = evalin('base','APthresholds');
            app.Ntraces = evalin('base','Ntraces');
            app.NumberAPperTrace = evalin('base','NumberAPperTrace');
            app.ymin = min(min(app.data(:, 2:2:end)));
            app.ymax = max(max(app.data(:, 2:2:end)));
            app.TraceSpinner.Value = 1;
            app.TraceSpinner.Limits = [1,max(app.Ntraces)];
            plotdata(app)
        end

        % Value changed function: TraceSpinner
        function TraceSpinnerValueChanged(app, event)
            plotdata(app);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete
        end

        % Callback function
        function DeselecttoexcludeTraceCheckBoxValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.TraceSpinnerLabel = uilabel(app.UIFigure);
            app.TraceSpinnerLabel.Text = 'Trace';
            app.TraceSpinner = uispinner(app.UIFigure);
            app.TraceSpinner.ValueChangedFcn = createCallbackFcn(app, @TraceSpinnerValueChanged, true);
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CC_INJ_TracesViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end