classdef OutputViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ResetdisplayButton          matlab.ui.control.Button
        EditField                   matlab.ui.control.EditField
        CATHARSiSoutputviewerLabel  matlab.ui.control.Label
        ExitButton                  matlab.ui.control.Button
        ROISpinner                  matlab.ui.control.Spinner
        ROISpinnerLabel             matlab.ui.control.Label
        UIAxes_5                    matlab.ui.control.UIAxes
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes_1                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Nrois           % number of ROIs
        Nframes         % total number of frames
        Output          % Output variable in base workspace (data exported to TXT for evaluation in Clampfit)
        finalROIs       % final ROIs
        scaleX          % x-axis scaling
        scaleY1         % y-axis scaling for signal 1 (UFASAR-demerged event times)
        scaleY2         % y-axis scaling for signal 2 (UFASAR-smoothed DETECTION CRITERION)
        scaleY3         % y-axis scaling for signal 3 (DETECTION CRITERION)
        scaleY4         % y-axis scaling for signal 4 (DF/F)
        scaleY5         % y-axis scaling for signal 5 (drift periods)
    end
    
    methods (Access = private)
        
        % ==============================================================================================================
        function plotdata(app)
            k = app.ROISpinner.Value;
            titles = {'Detected events', 'Detection criterion (processed)', 'Detection criterion (raw)', 'DF/F0', 'Drift periods'};
            for m = 1:5
                name = ['UIAxes_', num2str(m)];
                name2 = ['scaleY', num2str(m)];
                cla(app.(name), 'reset')
                app.(name).PositionConstraint = 'innerposition'; % it is critical that this comes first
                % (interactively resizing the figure or adding a title or axis labels activates automatic resizing)
                plot(app.(name), 1:app.Nframes, app.Output(:, (k-1)*5+m));                
                app.(name).XLim = app.scaleX;
                app.(name).YLim = app.(name2);
                ylabel(app.(name), titles{m});
                app.(name).TickDir = 'out';
                if m == 5
                    xlabel(app.(name), 'Time [frames]');
                else
                    app.(name).XTickLabel = '';
                end
                ylabel(app.(name), titles{m});
                app.(name).Box = 'off';
            end
            linkaxes([app.UIAxes_1, app.UIAxes_2, app.UIAxes_3, app.UIAxes_4, app.UIAxes_5], 'x');
        end

        % ==============================================================================================================
        function updateEditField(app)
            if sum(find(app.finalROIs == app.ROISpinner.Value)) > 0
                app.EditField.Value = "This ROI was included in analysis.";
                app.EditField.FontColor = [0, 0, 1];
            else
                app.EditField.Value = "This ROI was excluded from analysis.";
                app.EditField.FontColor = [1, 0, 0];
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            % get data
            app.Nrois = evalin('base','Nrois');
            app.Nframes = evalin('base','Nframes');
            app.Output = evalin('base','Output');
            app.finalROIs = evalin('base','finalROIs');
            % inital figure scaling
            app.scaleX = [0; app.Nframes];
            app.scaleY1 = [-0.05, 1.05];
            app.scaleY2 = [-0.05, max(max(app.Output(:, 2:5:end)))] .* 1.05;
            app.scaleY3 = [min(min(app.Output(:, 3:5:end))), max(max(app.Output(:, 3:5:end)))] .* 1.05;
            app.scaleY4 = [min(min(app.Output(:, 4:5:end))), max(max(app.Output(:, 4:5:end)))] .* 1.05;
            app.scaleY5 = [-0.05, 1.05];
            % initialize
            app.ROISpinner.Value = 1;
            app.ROISpinner.Limits = [1, app.Nrois];
            updateEditField(app)
            plotdata(app)
        end

        % Value changed function: ROISpinner
        function ROISpinnerValueChanged(app, event)
            updateEditField(app)
            plotdata(app)
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete
        end

        % Button pushed function: ResetdisplayButton
        function ResetdisplayButtonPushed(app, event)
            updateEditField(app)
            plotdata(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_1 = uiaxes(app.UIFigure);
            app.UIAxes_1.XTick = [];
            app.UIAxes_1.XTickLabel = '';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.XTickLabel = '';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.XTickLabel = '';
            app.UIAxes_5 = uiaxes(app.UIFigure);
            app.UIAxes_5.XTick = [];
            app.ROISpinnerLabel = uilabel(app.UIFigure);
            app.ROISpinnerLabel.Text = 'ROI';
            app.ROISpinner = uispinner(app.UIFigure);
            app.ROISpinner.ValueChangedFcn = createCallbackFcn(app, @ROISpinnerValueChanged, true);
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.CATHARSiSoutputviewerLabel = uilabel(app.UIFigure);
            app.CATHARSiSoutputviewerLabel.Text = 'CATHARSiS output viewer';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.ResetdisplayButton = uibutton(app.UIFigure, 'push');
            app.ResetdisplayButton.ButtonPushedFcn = createCallbackFcn(app, @ResetdisplayButtonPushed, true);
            app.ResetdisplayButton.Text = 'Reset display';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OutputViewer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end